classdef SudokuSolverApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SudokuSolver  matlab.ui.Figure
        SolveButton   matlab.ui.control.Button
        EditField_1   matlab.ui.control.NumericEditField
        EditField_2   matlab.ui.control.NumericEditField
        EditField_3   matlab.ui.control.NumericEditField
        EditField_4   matlab.ui.control.NumericEditField
        EditField_5   matlab.ui.control.NumericEditField
        EditField_6   matlab.ui.control.NumericEditField
        EditField_7   matlab.ui.control.NumericEditField
        EditField_8   matlab.ui.control.NumericEditField
        EditField_9   matlab.ui.control.NumericEditField
        EditField_10  matlab.ui.control.NumericEditField
        EditField_11  matlab.ui.control.NumericEditField
        EditField_12  matlab.ui.control.NumericEditField
        EditField_13  matlab.ui.control.NumericEditField
        EditField_14  matlab.ui.control.NumericEditField
        EditField_15  matlab.ui.control.NumericEditField
        EditField_16  matlab.ui.control.NumericEditField
        EditField_17  matlab.ui.control.NumericEditField
        EditField_18  matlab.ui.control.NumericEditField
        EditField_19  matlab.ui.control.NumericEditField
        EditField_20  matlab.ui.control.NumericEditField
        EditField_21  matlab.ui.control.NumericEditField
        EditField_22  matlab.ui.control.NumericEditField
        EditField_23  matlab.ui.control.NumericEditField
        EditField_24  matlab.ui.control.NumericEditField
        EditField_25  matlab.ui.control.NumericEditField
        EditField_26  matlab.ui.control.NumericEditField
        EditField_27  matlab.ui.control.NumericEditField
        EditField_28  matlab.ui.control.NumericEditField
        EditField_29  matlab.ui.control.NumericEditField
        EditField_30  matlab.ui.control.NumericEditField
        EditField_31  matlab.ui.control.NumericEditField
        EditField_32  matlab.ui.control.NumericEditField
        EditField_33  matlab.ui.control.NumericEditField
        EditField_34  matlab.ui.control.NumericEditField
        EditField_35  matlab.ui.control.NumericEditField
        EditField_36  matlab.ui.control.NumericEditField
        EditField_37  matlab.ui.control.NumericEditField
        EditField_38  matlab.ui.control.NumericEditField
        EditField_39  matlab.ui.control.NumericEditField
        EditField_40  matlab.ui.control.NumericEditField
        EditField_41  matlab.ui.control.NumericEditField
        EditField_42  matlab.ui.control.NumericEditField
        EditField_43  matlab.ui.control.NumericEditField
        EditField_44  matlab.ui.control.NumericEditField
        EditField_45  matlab.ui.control.NumericEditField
        EditField_46  matlab.ui.control.NumericEditField
        EditField_47  matlab.ui.control.NumericEditField
        EditField_48  matlab.ui.control.NumericEditField
        EditField_49  matlab.ui.control.NumericEditField
        EditField_50  matlab.ui.control.NumericEditField
        EditField_51  matlab.ui.control.NumericEditField
        EditField_52  matlab.ui.control.NumericEditField
        EditField_53  matlab.ui.control.NumericEditField
        EditField_54  matlab.ui.control.NumericEditField
        EditField_55  matlab.ui.control.NumericEditField
        EditField_56  matlab.ui.control.NumericEditField
        EditField_57  matlab.ui.control.NumericEditField
        EditField_58  matlab.ui.control.NumericEditField
        EditField_59  matlab.ui.control.NumericEditField
        EditField_60  matlab.ui.control.NumericEditField
        EditField_61  matlab.ui.control.NumericEditField
        EditField_62  matlab.ui.control.NumericEditField
        EditField_63  matlab.ui.control.NumericEditField
        EditField_64  matlab.ui.control.NumericEditField
        EditField_65  matlab.ui.control.NumericEditField
        EditField_66  matlab.ui.control.NumericEditField
        EditField_67  matlab.ui.control.NumericEditField
        EditField_68  matlab.ui.control.NumericEditField
        EditField_69  matlab.ui.control.NumericEditField
        EditField_70  matlab.ui.control.NumericEditField
        EditField_71  matlab.ui.control.NumericEditField
        EditField_72  matlab.ui.control.NumericEditField
        EditField_73  matlab.ui.control.NumericEditField
        EditField_74  matlab.ui.control.NumericEditField
        EditField_75  matlab.ui.control.NumericEditField
        EditField_76  matlab.ui.control.NumericEditField
        EditField_77  matlab.ui.control.NumericEditField
        EditField_78  matlab.ui.control.NumericEditField
        EditField_79  matlab.ui.control.NumericEditField
        EditField_80  matlab.ui.control.NumericEditField
        EditField_81  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Solutions = {}; % Description
    end
    
    methods (Access = private)
        
        function sudokusolver(app, sudokuBoard)
            %
            % Function:
            % - sudoku_solver: Solves the sudoku using backtracking
            %
            % Inputs:
            % - sudokuBoard: Sudoku board (9x9 double)
            %
            % Author: sgalella
            % https://github.com/sgalella
            
            % Backtracking algorithm
            for iRow = 1:size(sudokuBoard, 1)
                for iCol = 1:size(sudokuBoard, 2)
                    if sudokuBoard(iRow, iCol) == 0
                       for iNum = 1:9
                           if validmovement(app, sudokuBoard, iRow, iCol, iNum)
                              sudokuBoard(iRow, iCol) = iNum;
                              sudokusolver(app,sudokuBoard);
                              sudokuBoard(iRow, iCol) = 0;  % Backtracking
                           end
                       end
                       return 
                    end
                end
            end

            % Store the solution into a cell
            app.Solutions{end+1} = sudokuBoard;
            %printboard(app, sudokuBoard)
        end
        
        function isValid = validmovement(~, sudokuBoard, row, col, value)
            %
            % Function:
            % - validmovement: Checks if a value is valid at position (row, col)
            %
            % Inputs:
            % - sudokuBoard: Sudoku board (9x9 double)
            % - row: Row of the value to set (double)
            % - col: Col of the value to set (double)
            % - value: Desired number to set at (row, col) (double)
            % 
            % Outputs: 
            % – isValid: Returs true if value can be set at (row, col) (logical)
            %
            % Author: sgalella
            % https://github.com/sgalella
            
            
            % Initialize boolean
            isValid = true;
            
            % Check if value exist in row
            if ismember(value, sudokuBoard(row, :))
                isValid = false;
            end
            
            % Check if value exist in column
            if ismember(value, sudokuBoard(:, col))
                isValid = false;
            end
            
            % Check if value exist within square
            squareRow = floor((row-1)/3)*3;
            squareCol = floor((col-1)/3)*3;
            for iRow = 1:3
                for iCol = 1:3
                    if (sudokuBoard(squareRow+iRow, squareCol+iCol) == value)
                        isValid = false;
                    end
                end
            end
            
        end

        
        function printboard(~, sudokuBoard, title)
            %
            % Function:
            % - print_board: Prints the sudoku on the command window
            %
            % Inputs:
            % - sudokuBoard: Sudoku board (9x9 double)
            % - title: Title of the board
            %
            % Author: sgalella
            % https://github.com/sgalella
            
            
            if ~exist('title', 'var') 
                title = "Sudoku Board";
            end
            
            fprintf("\n%s\n",pad(title, 23, 'both', ' '))
            
            fprintf("\n");
            for iRow = 1:size(sudokuBoard,1) 
                fprintf(" ");
                for iCol = 1:size(sudokuBoard, 2)
                    if sudokuBoard(iRow, iCol) == 0
                        fprintf("· ", sudokuBoard(iRow, iCol));
                    else
                        fprintf("%d ", sudokuBoard(iRow, iCol));
                    end
                    if mod(iCol, 3) == 0 && iCol ~= size(sudokuBoard, 2)
                       fprintf("| "); 
                    end
                end
                if mod(iRow, 3) == 0 && iRow ~= size(sudokuBoard, 1)
                   fprintf("\n%s",pad('-', 23, 'both', '-'))
                end
                fprintf("\n");
            end
            fprintf("\n");
            
                        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
            sudokuBoard = zeros(9, 9);
            
            sudokuBoard(1, 1) = app.EditField_1.Value;
            sudokuBoard(1, 2) = app.EditField_2.Value;
            sudokuBoard(1, 3) = app.EditField_3.Value;
            sudokuBoard(1, 4) = app.EditField_4.Value;
            sudokuBoard(1, 5) = app.EditField_5.Value;
            sudokuBoard(1, 6) = app.EditField_6.Value;
            sudokuBoard(1, 7) = app.EditField_7.Value;
            sudokuBoard(1, 8) = app.EditField_8.Value;
            sudokuBoard(1, 9) = app.EditField_9.Value;
            
            sudokuBoard(2, 1) = app.EditField_10.Value;
            sudokuBoard(2, 2) = app.EditField_11.Value;
            sudokuBoard(2, 3) = app.EditField_12.Value;
            sudokuBoard(2, 4) = app.EditField_13.Value;
            sudokuBoard(2, 5) = app.EditField_14.Value;
            sudokuBoard(2, 6) = app.EditField_15.Value;
            sudokuBoard(2, 7) = app.EditField_16.Value;
            sudokuBoard(2, 8) = app.EditField_17.Value;
            sudokuBoard(2, 9) = app.EditField_18.Value;
            
            sudokuBoard(3, 1) = app.EditField_19.Value;
            sudokuBoard(3, 2) = app.EditField_20.Value;
            sudokuBoard(3, 3) = app.EditField_21.Value;
            sudokuBoard(3, 4) = app.EditField_22.Value;
            sudokuBoard(3, 5) = app.EditField_23.Value;
            sudokuBoard(3, 6) = app.EditField_24.Value;
            sudokuBoard(3, 7) = app.EditField_25.Value;
            sudokuBoard(3, 8) = app.EditField_26.Value;
            sudokuBoard(3, 9) = app.EditField_27.Value;
            
            sudokuBoard(4, 1) = app.EditField_28.Value;
            sudokuBoard(4, 2) = app.EditField_29.Value;
            sudokuBoard(4, 3) = app.EditField_30.Value;
            sudokuBoard(4, 4) = app.EditField_31.Value;
            sudokuBoard(4, 5) = app.EditField_32.Value;
            sudokuBoard(4, 6) = app.EditField_33.Value;
            sudokuBoard(4, 7) = app.EditField_34.Value;
            sudokuBoard(4, 8) = app.EditField_35.Value;
            sudokuBoard(4, 9) = app.EditField_36.Value;
            
            sudokuBoard(5, 1) = app.EditField_37.Value;
            sudokuBoard(5, 2) = app.EditField_38.Value;
            sudokuBoard(5, 3) = app.EditField_39.Value;
            sudokuBoard(5, 4) = app.EditField_40.Value;
            sudokuBoard(5, 5) = app.EditField_41.Value;
            sudokuBoard(5, 6) = app.EditField_42.Value;
            sudokuBoard(5, 7) = app.EditField_43.Value;
            sudokuBoard(5, 8) = app.EditField_44.Value;
            sudokuBoard(5, 9) = app.EditField_45.Value;
            
            sudokuBoard(6, 1) = app.EditField_46.Value;
            sudokuBoard(6, 2) = app.EditField_47.Value;
            sudokuBoard(6, 3) = app.EditField_48.Value;
            sudokuBoard(6, 4) = app.EditField_49.Value;
            sudokuBoard(6, 5) = app.EditField_50.Value;
            sudokuBoard(6, 6) = app.EditField_51.Value;
            sudokuBoard(6, 7) = app.EditField_52.Value;
            sudokuBoard(6, 8) = app.EditField_53.Value;
            sudokuBoard(6, 9) = app.EditField_54.Value;
            
            sudokuBoard(7, 1) = app.EditField_55.Value;
            sudokuBoard(7, 2) = app.EditField_56.Value;
            sudokuBoard(7, 3) = app.EditField_57.Value;
            sudokuBoard(7, 4) = app.EditField_58.Value;
            sudokuBoard(7, 5) = app.EditField_59.Value;
            sudokuBoard(7, 6) = app.EditField_60.Value;
            sudokuBoard(7, 7) = app.EditField_61.Value;
            sudokuBoard(7, 8) = app.EditField_62.Value;
            sudokuBoard(7, 9) = app.EditField_63.Value;
            
            sudokuBoard(8, 1) = app.EditField_64.Value;
            sudokuBoard(8, 2) = app.EditField_65.Value;
            sudokuBoard(8, 3) = app.EditField_66.Value;
            sudokuBoard(8, 4) = app.EditField_67.Value;
            sudokuBoard(8, 5) = app.EditField_68.Value;
            sudokuBoard(8, 6) = app.EditField_69.Value;
            sudokuBoard(8, 7) = app.EditField_70.Value;
            sudokuBoard(8, 8) = app.EditField_71.Value;
            sudokuBoard(8, 9) = app.EditField_72.Value;
            
            sudokuBoard(9, 1) = app.EditField_73.Value;
            sudokuBoard(9, 2) = app.EditField_74.Value;
            sudokuBoard(9, 3) = app.EditField_75.Value;
            sudokuBoard(9, 4) = app.EditField_76.Value;
            sudokuBoard(9, 5) = app.EditField_77.Value;
            sudokuBoard(9, 6) = app.EditField_78.Value;
            sudokuBoard(9, 7) = app.EditField_79.Value;
            sudokuBoard(9, 8) = app.EditField_80.Value;
            sudokuBoard(9, 9) = app.EditField_81.Value;
            
            sudokusolver(app, sudokuBoard);
                        
            app.EditField_1.Value = app.Solutions{1}(1, 1);
            app.EditField_2.Value = app.Solutions{1}(1, 2);
            app.EditField_3.Value = app.Solutions{1}(1, 3);
            app.EditField_4.Value = app.Solutions{1}(1, 4);
            app.EditField_5.Value = app.Solutions{1}(1, 5);
            app.EditField_6.Value = app.Solutions{1}(1, 6);
            app.EditField_7.Value = app.Solutions{1}(1, 7);
            app.EditField_8.Value = app.Solutions{1}(1, 8);
            app.EditField_9.Value = app.Solutions{1}(1, 9);
            
            app.EditField_10.Value = app.Solutions{1}(2, 1);
            app.EditField_11.Value = app.Solutions{1}(2, 2);
            app.EditField_12.Value = app.Solutions{1}(2, 3);
            app.EditField_13.Value = app.Solutions{1}(2, 4);
            app.EditField_14.Value = app.Solutions{1}(2, 5);
            app.EditField_15.Value = app.Solutions{1}(2, 6);
            app.EditField_16.Value = app.Solutions{1}(2, 7);
            app.EditField_17.Value = app.Solutions{1}(2, 8);
            app.EditField_18.Value = app.Solutions{1}(2, 9);
            
            app.EditField_19.Value = app.Solutions{1}(3, 1);
            app.EditField_20.Value = app.Solutions{1}(3, 2);
            app.EditField_21.Value = app.Solutions{1}(3, 3);
            app.EditField_22.Value = app.Solutions{1}(3, 4);
            app.EditField_23.Value = app.Solutions{1}(3, 5);
            app.EditField_24.Value = app.Solutions{1}(3, 6);
            app.EditField_25.Value = app.Solutions{1}(3, 7);
            app.EditField_26.Value = app.Solutions{1}(3, 8);
            app.EditField_27.Value = app.Solutions{1}(3, 9);
            
            app.EditField_28.Value = app.Solutions{1}(4, 1);
            app.EditField_29.Value = app.Solutions{1}(4, 2);
            app.EditField_30.Value = app.Solutions{1}(4, 3);
            app.EditField_31.Value = app.Solutions{1}(4, 4);
            app.EditField_32.Value = app.Solutions{1}(4, 5);
            app.EditField_33.Value = app.Solutions{1}(4, 6);
            app.EditField_34.Value = app.Solutions{1}(4, 7);
            app.EditField_35.Value = app.Solutions{1}(4, 8);
            app.EditField_36.Value = app.Solutions{1}(4, 9);
            
            app.EditField_37.Value = app.Solutions{1}(5, 1);
            app.EditField_38.Value = app.Solutions{1}(5, 2);
            app.EditField_39.Value = app.Solutions{1}(5, 3);
            app.EditField_40.Value = app.Solutions{1}(5, 4);
            app.EditField_41.Value = app.Solutions{1}(5, 5);
            app.EditField_42.Value = app.Solutions{1}(5, 6);
            app.EditField_43.Value = app.Solutions{1}(5, 7);
            app.EditField_44.Value = app.Solutions{1}(5, 8);
            app.EditField_45.Value = app.Solutions{1}(5, 9);
            
            app.EditField_46.Value = app.Solutions{1}(6, 1);
            app.EditField_47.Value = app.Solutions{1}(6, 2);
            app.EditField_48.Value = app.Solutions{1}(6, 3);
            app.EditField_49.Value = app.Solutions{1}(6, 4);
            app.EditField_50.Value = app.Solutions{1}(6, 5);
            app.EditField_51.Value = app.Solutions{1}(6, 6);
            app.EditField_52.Value = app.Solutions{1}(6, 7);
            app.EditField_53.Value = app.Solutions{1}(6, 8);
            app.EditField_54.Value = app.Solutions{1}(6, 9);
            
            app.EditField_55.Value = app.Solutions{1}(7, 1);
            app.EditField_56.Value = app.Solutions{1}(7, 2);
            app.EditField_57.Value = app.Solutions{1}(7, 3);
            app.EditField_58.Value = app.Solutions{1}(7, 4);
            app.EditField_59.Value = app.Solutions{1}(7, 5);
            app.EditField_60.Value = app.Solutions{1}(7, 6);
            app.EditField_61.Value = app.Solutions{1}(7, 7);
            app.EditField_62.Value = app.Solutions{1}(7, 8);
            app.EditField_63.Value = app.Solutions{1}(7, 9);
            
            app.EditField_64.Value = app.Solutions{1}(8, 1);
            app.EditField_65.Value = app.Solutions{1}(8, 2);
            app.EditField_66.Value = app.Solutions{1}(8, 3);
            app.EditField_67.Value = app.Solutions{1}(8, 4);
            app.EditField_68.Value = app.Solutions{1}(8, 5);
            app.EditField_69.Value = app.Solutions{1}(8, 6);
            app.EditField_70.Value = app.Solutions{1}(8, 7);
            app.EditField_71.Value = app.Solutions{1}(8, 8);
            app.EditField_72.Value = app.Solutions{1}(8, 9);
            
            app.EditField_73.Value = app.Solutions{1}(9, 1);
            app.EditField_74.Value = app.Solutions{1}(9, 2);
            app.EditField_75.Value = app.Solutions{1}(9, 3);
            app.EditField_76.Value = app.Solutions{1}(9, 4);
            app.EditField_77.Value = app.Solutions{1}(9, 5);
            app.EditField_78.Value = app.Solutions{1}(9, 6);
            app.EditField_79.Value = app.Solutions{1}(9, 7);
            app.EditField_80.Value = app.Solutions{1}(9, 8);
            app.EditField_81.Value = app.Solutions{1}(9, 9);
            
            solvedColor = [173, 255, 47]/255;
            
            app.EditField_1.BackgroundColor = solvedColor;
            app.EditField_2.BackgroundColor = solvedColor;
            app.EditField_3.BackgroundColor = solvedColor;
            app.EditField_4.BackgroundColor = solvedColor;
            app.EditField_5.BackgroundColor = solvedColor;
            app.EditField_6.BackgroundColor = solvedColor;
            app.EditField_7.BackgroundColor = solvedColor;
            app.EditField_8.BackgroundColor = solvedColor;
            app.EditField_9.BackgroundColor = solvedColor;
            
            app.EditField_10.BackgroundColor = solvedColor;
            app.EditField_11.BackgroundColor = solvedColor;
            app.EditField_12.BackgroundColor = solvedColor;
            app.EditField_13.BackgroundColor = solvedColor;
            app.EditField_14.BackgroundColor = solvedColor;
            app.EditField_15.BackgroundColor = solvedColor;
            app.EditField_16.BackgroundColor = solvedColor;
            app.EditField_17.BackgroundColor = solvedColor;
            app.EditField_18.BackgroundColor = solvedColor;
            
            app.EditField_19.BackgroundColor = solvedColor;
            app.EditField_20.BackgroundColor = solvedColor;
            app.EditField_21.BackgroundColor = solvedColor;
            app.EditField_22.BackgroundColor = solvedColor;
            app.EditField_23.BackgroundColor = solvedColor;
            app.EditField_24.BackgroundColor = solvedColor;
            app.EditField_25.BackgroundColor = solvedColor;
            app.EditField_26.BackgroundColor = solvedColor;
            app.EditField_27.BackgroundColor = solvedColor;
            
            app.EditField_28.BackgroundColor = solvedColor;
            app.EditField_29.BackgroundColor = solvedColor;
            app.EditField_30.BackgroundColor = solvedColor;
            app.EditField_31.BackgroundColor = solvedColor;
            app.EditField_32.BackgroundColor = solvedColor;
            app.EditField_33.BackgroundColor = solvedColor;
            app.EditField_34.BackgroundColor = solvedColor;
            app.EditField_35.BackgroundColor = solvedColor;
            app.EditField_36.BackgroundColor = solvedColor;
            
            app.EditField_37.BackgroundColor = solvedColor;
            app.EditField_38.BackgroundColor = solvedColor;
            app.EditField_39.BackgroundColor = solvedColor;
            app.EditField_40.BackgroundColor = solvedColor;
            app.EditField_41.BackgroundColor = solvedColor;
            app.EditField_42.BackgroundColor = solvedColor;
            app.EditField_43.BackgroundColor = solvedColor;
            app.EditField_44.BackgroundColor = solvedColor;
            app.EditField_45.BackgroundColor = solvedColor;
            
            app.EditField_46.BackgroundColor = solvedColor;
            app.EditField_47.BackgroundColor = solvedColor;
            app.EditField_48.BackgroundColor = solvedColor;
            app.EditField_49.BackgroundColor = solvedColor;
            app.EditField_50.BackgroundColor = solvedColor;
            app.EditField_51.BackgroundColor = solvedColor;
            app.EditField_52.BackgroundColor = solvedColor;
            app.EditField_53.BackgroundColor = solvedColor;
            app.EditField_54.BackgroundColor = solvedColor;
            
            app.EditField_55.BackgroundColor = solvedColor;
            app.EditField_56.BackgroundColor = solvedColor;
            app.EditField_57.BackgroundColor = solvedColor;
            app.EditField_58.BackgroundColor = solvedColor;
            app.EditField_59.BackgroundColor = solvedColor;
            app.EditField_60.BackgroundColor = solvedColor;
            app.EditField_61.BackgroundColor = solvedColor;
            app.EditField_62.BackgroundColor = solvedColor;
            app.EditField_63.BackgroundColor = solvedColor;
            
            app.EditField_64.BackgroundColor = solvedColor;
            app.EditField_65.BackgroundColor = solvedColor;
            app.EditField_66.BackgroundColor = solvedColor;
            app.EditField_67.BackgroundColor = solvedColor;
            app.EditField_68.BackgroundColor = solvedColor;
            app.EditField_69.BackgroundColor = solvedColor;
            app.EditField_70.BackgroundColor = solvedColor;
            app.EditField_71.BackgroundColor = solvedColor;
            app.EditField_72.BackgroundColor = solvedColor;
            
            app.EditField_73.BackgroundColor = solvedColor;
            app.EditField_74.BackgroundColor = solvedColor;
            app.EditField_75.BackgroundColor = solvedColor;
            app.EditField_76.BackgroundColor = solvedColor;
            app.EditField_77.BackgroundColor = solvedColor;
            app.EditField_78.BackgroundColor = solvedColor;
            app.EditField_79.BackgroundColor = solvedColor;
            app.EditField_80.BackgroundColor = solvedColor;
            app.EditField_81.BackgroundColor = solvedColor;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SudokuSolver = uifigure('Visible', 'off');
            app.SudokuSolver.Name = 'Sudoku Solver';
            app.SolveButton = uibutton(app.SudokuSolver, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.EditField_1 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_1.Limits = [0 9];
            app.EditField_1.Value = 5;
            app.EditField_2 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_2.Limits = [0 9];
            app.EditField_2.Value = 3;
            app.EditField_3 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_3.Limits = [0 9];
            app.EditField_4 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_4.Limits = [0 9];
            app.EditField_5 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_5.Limits = [0 9];
            app.EditField_5.Value = 7;
            app.EditField_6 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_6.Limits = [0 9];
            app.EditField_7 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_7.Limits = [0 9];
            app.EditField_8 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_8.Limits = [0 9];
            app.EditField_9 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_9.Limits = [0 9];
            app.EditField_10 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_10.Limits = [0 9];
            app.EditField_10.Value = 6;
            app.EditField_11 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_11.Limits = [0 9];
            app.EditField_12 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_12.Limits = [0 9];
            app.EditField_13 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_13.Limits = [0 9];
            app.EditField_13.Value = 1;
            app.EditField_14 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_14.Limits = [0 9];
            app.EditField_14.Value = 9;
            app.EditField_15 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_15.Limits = [0 9];
            app.EditField_15.Value = 5;
            app.EditField_16 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_16.Limits = [0 9];
            app.EditField_17 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_17.Limits = [0 9];
            app.EditField_18 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_18.Limits = [0 9];
            app.EditField_19 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_19.Limits = [0 9];
            app.EditField_20 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_20.Limits = [0 9];
            app.EditField_20.Value = 9;
            app.EditField_21 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_21.Limits = [0 9];
            app.EditField_21.Value = 8;
            app.EditField_22 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_22.Limits = [0 9];
            app.EditField_23 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_23.Limits = [0 9];
            app.EditField_24 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_24.Limits = [0 9];
            app.EditField_25 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_25.Limits = [0 9];
            app.EditField_26 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_26.Limits = [0 9];
            app.EditField_26.Value = 6;
            app.EditField_27 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_27.Limits = [0 9];
            app.EditField_28 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_28.Limits = [0 9];
            app.EditField_28.Value = 8;
            app.EditField_29 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_29.Limits = [0 9];
            app.EditField_30 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_30.Limits = [0 9];
            app.EditField_31 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_31.Limits = [0 9];
            app.EditField_32 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_32.Limits = [0 9];
            app.EditField_32.Value = 6;
            app.EditField_33 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_33.Limits = [0 9];
            app.EditField_34 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_34.Limits = [0 9];
            app.EditField_35 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_35.Limits = [0 9];
            app.EditField_36 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_36.Limits = [0 9];
            app.EditField_36.Value = 3;
            app.EditField_37 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_37.Limits = [0 9];
            app.EditField_37.Value = 4;
            app.EditField_38 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_38.Limits = [0 9];
            app.EditField_39 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_39.Limits = [0 9];
            app.EditField_40 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_40.Limits = [0 9];
            app.EditField_40.Value = 8;
            app.EditField_41 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_41.Limits = [0 9];
            app.EditField_42 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_42.Limits = [0 9];
            app.EditField_42.Value = 3;
            app.EditField_43 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_43.Limits = [0 9];
            app.EditField_44 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_44.Limits = [0 9];
            app.EditField_45 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_45.Limits = [0 9];
            app.EditField_45.Value = 1;
            app.EditField_46 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_46.Limits = [0 9];
            app.EditField_46.Value = 7;
            app.EditField_47 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_47.Limits = [0 9];
            app.EditField_48 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_48.Limits = [0 9];
            app.EditField_49 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_49.Limits = [0 9];
            app.EditField_50 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_50.Limits = [0 9];
            app.EditField_50.Value = 2;
            app.EditField_51 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_51.Limits = [0 9];
            app.EditField_52 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_52.Limits = [0 9];
            app.EditField_53 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_53.Limits = [0 9];
            app.EditField_54 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_54.Limits = [0 9];
            app.EditField_54.Value = 6;
            app.EditField_55 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_55.Limits = [0 9];
            app.EditField_56 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_56.Limits = [0 9];
            app.EditField_56.Value = 6;
            app.EditField_57 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_57.Limits = [0 9];
            app.EditField_58 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_58.Limits = [0 9];
            app.EditField_59 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_59.Limits = [0 9];
            app.EditField_60 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_60.Limits = [0 9];
            app.EditField_61 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_61.Limits = [0 9];
            app.EditField_61.Value = 2;
            app.EditField_62 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_62.Limits = [0 9];
            app.EditField_62.Value = 8;
            app.EditField_63 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_63.Limits = [0 9];
            app.EditField_64 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_64.Limits = [0 9];
            app.EditField_65 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_65.Limits = [0 9];
            app.EditField_66 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_66.Limits = [0 9];
            app.EditField_67 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_67.Limits = [0 9];
            app.EditField_67.Value = 4;
            app.EditField_68 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_68.Limits = [0 9];
            app.EditField_68.Value = 1;
            app.EditField_69 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_69.Limits = [0 9];
            app.EditField_69.Value = 9;
            app.EditField_70 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_70.Limits = [0 9];
            app.EditField_71 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_71.Limits = [0 9];
            app.EditField_72 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_72.Limits = [0 9];
            app.EditField_72.Value = 5;
            app.EditField_73 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_73.Limits = [0 9];
            app.EditField_74 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_74.Limits = [0 9];
            app.EditField_75 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_75.Limits = [0 9];
            app.EditField_76 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_76.Limits = [0 9];
            app.EditField_77 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_77.Limits = [0 9];
            app.EditField_77.Value = 8;
            app.EditField_78 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_78.Limits = [0 9];
            app.EditField_79 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_79.Limits = [0 9];
            app.EditField_80 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_80.Limits = [0 9];
            app.EditField_80.Value = 7;
            app.EditField_81 = uieditfield(app.SudokuSolver, 'numeric');
            app.EditField_81.Limits = [0 9];
            app.EditField_81.Value = 9;
            % Show the figure after all components are created
            app.SudokuSolver.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SudokuSolverApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SudokuSolver)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SudokuSolver)
        end
    end
end